classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartSimulationButton        matlab.ui.control.StateButton
        ResetCartButton              matlab.ui.control.Button
        InitialAngledegSliderLabel   matlab.ui.control.Label
        InitialAngledegSlider        matlab.ui.control.Slider
        KxSpinnerLabel               matlab.ui.control.Label
        KxSpinner                    matlab.ui.control.Spinner
        KvSpinnerLabel               matlab.ui.control.Label
        KvSpinner                    matlab.ui.control.Spinner
        KomegaSpinnerLabel           matlab.ui.control.Label
        KomegaSpinner                matlab.ui.control.Spinner
        ControlModeButtonGroup       matlab.ui.container.ButtonGroup
        ArrowKeyOnlyButton           matlab.ui.control.RadioButton
        PControllerButton            matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
        KthetaSpinnerLabel           matlab.ui.control.Label
        KthetaSpinner                matlab.ui.control.Spinner
        ResetGainsButton             matlab.ui.control.Button
        ConfirmButton                matlab.ui.control.Button
        TypeinInitialAngleEditFieldLabel  matlab.ui.control.Label
        TypeinInitialAngleEditField  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: ControlModeButtonGroup
        function ControlModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlModeButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Arrow Key Only"
                setPControllerVisibility(app,"off");
            else
                setPControllerVisibility(app,"on");
            end
            
            % A helper function to set the Visibility of P controller
            % setters.
            function setPControllerVisibility(app,on_off_flag)
                tohide = [app.KthetaSpinner,app.KthetaSpinnerLabel,...
                      app.KomegaSpinner,app.KomegaSpinnerLabel,...
                      app.KvSpinner,app.KvSpinnerLabel,...
                      app.KxSpinner,app.KxSpinnerLabel];
                for h = tohide
                    h.Enable = on_off_flag;
                end
            end
        end

        % Value changed function: StartSimulationButton
        function StartSimulationButtonValueChanged(app, event)
            value = app.StartSimulationButton.Value;
            if value==1
                app.InitialAngledegSlider.Enable='off';
                app.InitialAngledegSliderLabel.Enable='off';
                app.StartSimulationButton.Text = 'Press to Pause';
            else
                app.StartSimulationButton.Text = 'Start Simulation';
            end
        end

        % Button pushed function: ResetCartButton
        function ResetCartButtonPushed(app, event)
            app.InitialAngledegSlider.Enable='on';
            app.InitialAngledegSliderLabel.Enable='on';
            app.StartSimulationButton.Value = 0;
            % Manually call the simulation button changed event. [] means
            % an empty event.
            StartSimulationButtonValueChanged(app,[]);
        end

        % Value changing function: InitialAngledegSlider
        function InitialAngledegSliderValueChanging(app, event)
            changingValue = event.Value;
            app.TypeinInitialAngleEditField.Value=changingValue;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    app.u = app.u-1;
                case 'rightarrow'
                    app.u = app.u+1;
            end
        
            disp(app.u)
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
             switch key
                case 'leftarrow'
                    app.u = app.u+1;
                case 'rightarrow'
                    app.u = app.u-1;
             end
             disp(app.u)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.InitialAngledegSlider.Value=app.TypeinInitialAngleEditField.Value;
        end

        % Value changed function: TypeinInitialAngleEditField
        function TypeinInitialAngleEditFieldValueChanged(app, event)
            value = app.TypeinInitialAngleEditField.Value;
          app.InitialAngledegSlider.Value=value;
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.StartSimulationButton = uibutton(app.UIFigure, 'state');
            app.StartSimulationButton.ValueChangedFcn = createCallbackFcn(app, @StartSimulationButtonValueChanged, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ResetCartButton = uibutton(app.UIFigure, 'push');
            app.ResetCartButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCartButtonPushed, true);
            app.ResetCartButton.Text = 'Reset Cart';
            app.InitialAngledegSliderLabel = uilabel(app.UIFigure);
            app.InitialAngledegSliderLabel.Text = 'Initial Angle(deg)';
            app.InitialAngledegSlider = uislider(app.UIFigure);
            app.InitialAngledegSlider.Limits = [-180 180];
            app.InitialAngledegSlider.ValueChangingFcn = createCallbackFcn(app, @InitialAngledegSliderValueChanging, true);
            app.KxSpinnerLabel = uilabel(app.UIFigure);
            app.KxSpinnerLabel.Enable = 'off';
            app.KxSpinnerLabel.Text = 'Kx ';
            app.KxSpinner = uispinner(app.UIFigure);
            app.KxSpinner.Step = 0.01;
            app.KxSpinner.Enable = 'off';
            app.KxSpinner.Value = 0.572;
            app.KvSpinnerLabel = uilabel(app.UIFigure);
            app.KvSpinnerLabel.Enable = 'off';
            app.KvSpinnerLabel.Text = 'Kv';
            app.KvSpinner = uispinner(app.UIFigure);
            app.KvSpinner.Step = 0.01;
            app.KvSpinner.Enable = 'off';
            app.KvSpinner.Value = 2.12;
            app.KomegaSpinnerLabel = uilabel(app.UIFigure);
            app.KomegaSpinnerLabel.Enable = 'off';
            app.KomegaSpinnerLabel.Text = 'Komega';
            app.KomegaSpinner = uispinner(app.UIFigure);
            app.KomegaSpinner.Step = 0.01;
            app.KomegaSpinner.Enable = 'off';
            app.KomegaSpinner.Value = 4.02;
            app.ControlModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ControlModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlModeButtonGroupSelectionChanged, true);
            app.ControlModeButtonGroup.Title = 'Control Mode';
            app.ArrowKeyOnlyButton = uiradiobutton(app.ControlModeButtonGroup);
            app.ArrowKeyOnlyButton.Text = 'Arrow Key Only';
            app.ArrowKeyOnlyButton.Value = true;
            app.PControllerButton = uiradiobutton(app.ControlModeButtonGroup);
            app.PControllerButton.Text = 'P Controller';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.KthetaSpinnerLabel = uilabel(app.UIFigure);
            app.KthetaSpinnerLabel.Enable = 'off';
            app.KthetaSpinnerLabel.Text = 'Ktheta';
            app.KthetaSpinner = uispinner(app.UIFigure);
            app.KthetaSpinner.Step = 0.01;
            app.KthetaSpinner.Enable = 'off';
            app.KthetaSpinner.Value = 15.7;
            app.ResetGainsButton = uibutton(app.UIFigure, 'push');
            app.ResetGainsButton.Enable = 'off';
            app.ResetGainsButton.Text = 'Reset Gains';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.TypeinInitialAngleEditFieldLabel = uilabel(app.UIFigure);
            app.TypeinInitialAngleEditFieldLabel.Text = 'Type in Initial Angle';
            app.TypeinInitialAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TypeinInitialAngleEditField.Limits = [-180 180];
            app.TypeinInitialAngleEditField.ValueChangedFcn = createCallbackFcn(app, @TypeinInitialAngleEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end