classdef StudyingAppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PSliderLabel           matlab.ui.control.Label
        PSlider                matlab.ui.control.Slider
        PValueLabel            matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        SimulationStartButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        simulating = false; % Description
        x = linspace(0,pi,100);
        phase = 0;
        y = sin(2*x+phase*pi/10)+app.PSlider.Value;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            for i = 1:100
                pause(0.2);
                if not app.simulating
                    continue;
                else
                    app.phase=app.phase+1;
                    app.y =  10*sin(2*app.x+app.phase*pi/10)+app.PSlider.Value;
                    plot(app.UIAxes,app.x,app.y);
                    xlim(app.UIAxes,[0 pi]);
                    ylim(app.UIAxes,[0 100]);
                end
            end
        end

        % Value changing function: PSlider
        function PSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PValueLabel.Text = "P value="+changingValue
        end

        % Button pushed function: SimulationStartButton
        function SimulationStartButtonPushed(app, event)
            app.simulating = not app.simulating; % Flip the simulating bit.
            if app.simulating
                app.SimulationStartButton.Text='Pause Simulation' 
            else
                app.SimulationStartButton.Text = "Start Simulation";
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PSliderLabel = uilabel(app.UIFigure);
            app.PSliderLabel.Text = 'P';
            app.PSlider = uislider(app.UIFigure);
            app.PSlider.ValueChangingFcn = createCallbackFcn(app, @PSliderValueChanging, true);
            app.PValueLabel = uilabel(app.UIFigure);
            app.PValueLabel.Text = 'P Value=';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimulationStartButton = uibutton(app.UIFigure, 'push');
            app.SimulationStartButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationStartButtonPushed, true);
            app.SimulationStartButton.Text = 'Simulation Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StudyingAppDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end